classdef AMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartSequenceButton          matlab.ui.control.Button
        GripforceButton              matlab.ui.control.Button
        TorqueStabilisationButton    matlab.ui.control.Button
        ZeroassistedButton           matlab.ui.control.Button
        WelcometotheexperimentLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        PleaseinputyournameTextAreaLabel  matlab.ui.control.Label
        UsernameTextArea             matlab.ui.control.TextArea
        GripTargetTrackingButton     matlab.ui.control.Button
        HI5TargetTrackingLabel       matlab.ui.control.Label
        FullassistedButton           matlab.ui.control.Button
        SemiassistedButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSequenceButton
        function StartSequenceButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_grip
        end

        % Button pushed function: ZeroassistedButton
        function ZeroassistedButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_AngleDisp
        end

        % Button pushed function: GripforceButton
        function GripforceButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip
        end

        % Button pushed function: TorqueStabilisationButton
        function TorqueStabilisationButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_torque
        end

        % Button pushed function: GripTargetTrackingButton
        function GripTargetTrackingButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip_tracking            
        end

        % Button pushed function: FullassistedButton
        function FullassistedButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_FullAssisted_targettracking     
        end

        % Button pushed function: SemiassistedButton
        function SemiassistedButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_SemiAssisted_targettracking            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartSequenceButton = uibutton(app.UIFigure, 'push');
            app.StartSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @StartSequenceButtonPushed, true);
            app.StartSequenceButton.Text = {'Start Sequence'; ''};
            app.GripforceButton = uibutton(app.UIFigure, 'push');
            app.GripforceButton.ButtonPushedFcn = createCallbackFcn(app, @GripforceButtonPushed, true);
            app.GripforceButton.Text = 'Grip force';
            app.TorqueStabilisationButton = uibutton(app.UIFigure, 'push');
            app.TorqueStabilisationButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueStabilisationButtonPushed, true);
            app.TorqueStabilisationButton.Text = 'Torque Stabilisation';
            app.ZeroassistedButton = uibutton(app.UIFigure, 'push');
            app.ZeroassistedButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroassistedButtonPushed, true);
            app.ZeroassistedButton.Text = 'Zero-assisted';
            app.WelcometotheexperimentLabel = uilabel(app.UIFigure);
            app.WelcometotheexperimentLabel.Text = 'Welcome to the experiment!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'I hope that the EEG and EMG electrodes are not annoying you too much. I''d like to invite you to a sequence of experiments constituting our study on wrist neuromechanics. During the following exercises you should be relaxed, avoid excessive blinking and swallowing. You should try to minimize the muscle contraction and stay calm. When you are ready, hit the Start button!';
            app.PleaseinputyournameTextAreaLabel = uilabel(app.UIFigure);
            app.PleaseinputyournameTextAreaLabel.Text = 'Please input your name:';
            app.UsernameTextArea = uitextarea(app.UIFigure);
            app.GripTargetTrackingButton = uibutton(app.UIFigure, 'push');
            app.GripTargetTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @GripTargetTrackingButtonPushed, true);
            app.GripTargetTrackingButton.Text = 'Grip Target Tracking';
            app.HI5TargetTrackingLabel = uilabel(app.UIFigure);
            app.HI5TargetTrackingLabel.Text = 'HI5 Target Tracking:';
            app.FullassistedButton = uibutton(app.UIFigure, 'push');
            app.FullassistedButton.ButtonPushedFcn = createCallbackFcn(app, @FullassistedButtonPushed, true);
            app.FullassistedButton.Text = 'Full-assisted';
            app.SemiassistedButton = uibutton(app.UIFigure, 'push');
            app.SemiassistedButton.ButtonPushedFcn = createCallbackFcn(app, @SemiassistedButtonPushed, true);
            app.SemiassistedButton.Text = 'Semi-assisted';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end