classdef App_grip_tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        red                     matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        trialCounter            matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        StartTrainingButton     matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        instructionLabel        matlab.ui.control.Label
        NextButton              matlab.ui.control.Button
        ErrorTextAreaLabel      matlab.ui.control.Label
        ErrorTextArea           matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        ScoreTextAreaLabel      matlab.ui.control.Label
        ScoreTextArea           matlab.ui.control.TextArea
        UIAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
function checkPauseStopStatus(app, experiment)
        clear u_pause
        u_pause = udpport('LocalPort', 2222);
        if app.PauseButton.Value
            app.counter.Visible=true;
            app.counter.Text='Paused'; 
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
            waitfor(app.PauseButton,'Value',false)
            write(u_pause, strcat(num2str(app.PauseButton.Value)), 'string','LocalHost', 1111);
        end
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.space.Visible=true;
        app.enter.Visible=false;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false;
        app.logic.Enable = false;
        app.ErrorTextArea.Visible=false;
        app.ErrorTextAreaLabel.Visible=false;
        app.Label.Visible = false;
        
        %init udp
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",3000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        %variables
        trial_length = 10;
        numTrials = 50;
        trials = 1;
        trial_pre = 1;
        
        %initialize
        experiment = 6; %experiment name for transmission
        countdown = 3;

        %circle calcs and display
        plot(app.UIAxes,1,1)
        xline(app.UIAxes,0, '--','color','white','LineWidth',2)
        set(app.UIAxes,'Color',[0.5 0.5 0.5])
        set(app.UIAxes,'visible','off')
        app.UIAxes.Position = [493 100 1280 500];
        
        app.ScoreTextArea.Value = num2str(0);
        %App_udp_experiment_function();
        write(u3, strcat(num2str(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(0)), 'string','LocalHost', 1000);
        flush(u1);
        while (trials <= numTrials)
            app.trialCounter.Text=[num2str(trials)];
            
            if ~isempty(app)%pause button functionality
                checkPauseStopStatus(app)
            end
            
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            clockCurrent = clockStart;
            
            Elapsed_Time = clockCurrent - clockStart;
            data_box = [];
            
            while (Elapsed_Time < trial_length + countdown ) % -0.02 for delay compensation
%                 app.Label.Text=['Trial # ' num2str(Elapsed_Time)];
                c = clock;
                clockCurrent = c(4)*3600+c(5)*60+c(6);
                Elapsed_Time = clockCurrent - clockStart;
                if(Elapsed_Time(1) > 3)
                    app.counter.Text = '';
                elseif(Elapsed_Time(1) > 2.25)
                    app.counter.Text='1';
                elseif (Elapsed_Time(1) > 1.5)
                    app.counter.Text='2';
                elseif (Elapsed_Time(1) > 0.75)
                    app.counter.Text='3';
                elseif (Elapsed_Time(1) > 0)
                    app.counter.Text='Ready..';
                end
                
                if (trials == numTrials) && (Elapsed_Time > trial_length + countdown - 0.2)
                    trials = trials + 1;
                    break
                end
                
                data_int_array = int8(read(u1,124,"int8"));  % ensure buffer is multiple of number of electrodes used 
                
                for i = 1:4:121
                    single_data_sample = typecast(data_int_array(i:i+3),'single');
                    data_box = [data_box single_data_sample];
                end
                
                f_targ = data_box(1:10);
                f = data_box(11:20);
                Error = data_box(21);
%                 Elapsed_Time = data_box(22:31);
                trial_num = data_box(22:31);
                data_box = [];
                k = 1;
                
                trials = trial_num(1);
                if trial_pre ~= trials
                    trial_pre = trials;
                    break
                end
                
                posError = -100.*f;
                angDeviation = posError./6400*90;
                sinPos = f_targ;
                
                blueY= 6.8.*(sinPos)+335;
                
                angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))./0.25) .* 0.25;
                redY=-10.*angDeviation+100;
                while k <= 10
                    app.blue.Position=[615,blueY(k),50,50];
                    app.red.Position=[625,redY(k),30,30];
                    k = k+1;
                end
                
            end
            
            app.ScoreTextArea.Value = num2str(floor(Error));
%             trials = trials + 1;
        
        end
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.rightArrow.Visible=true;
        app.NextButton.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic.Enable=true;
        app.trialCounter.Text=' ';
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic.Enable
                        StartPositionButtonPushed(app, event);
                    else
                        StartButtonPushed(app, event);
                    end
                case 'rightarrow'
                    if app.logic.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            App_torque;
            app.delete;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your arm to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.Visible = 'off';
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.Visible = 'off';
            app.StartTrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Visible = 'off';
            app.ErrorTextAreaLabel.Text = 'Error:';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.ErrorTextArea.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.ScoreTextAreaLabel = uilabel(app.UIFigure);
            app.ScoreTextAreaLabel.Text = 'Score:';
            app.ScoreTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_grip_tracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end